classdef Prueba_app_1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        ComunicacionButton              matlab.ui.control.StateButton
        GirosXEditField                 matlab.ui.control.NumericEditField
        GirosXEditFieldLabel            matlab.ui.control.Label
        RPMXEditField                   matlab.ui.control.NumericEditField
        RPMXEditFieldLabel              matlab.ui.control.Label
        DisminuirRPMZButton             matlab.ui.control.Button
        AumentarGirosZButton            matlab.ui.control.Button
        SENTIDOMOTORZSwitch             matlab.ui.control.Switch
        SENTIDOMOTORZSwitchLabel        matlab.ui.control.Label
        AumentarRPMZButton              matlab.ui.control.Button
        VARIABLESMOTORZLabel            matlab.ui.control.Label
        DisminuirGirosZButton           matlab.ui.control.Button
        GirosZEditField                 matlab.ui.control.NumericEditField
        GirosZEditFieldLabel            matlab.ui.control.Label
        RPMZEditField                   matlab.ui.control.NumericEditField
        RPMZEditFieldLabel              matlab.ui.control.Label
        INDUCTIVOMOTORZ2Lamp            matlab.ui.control.Lamp
        INDUCTIVOMOTORY2Label           matlab.ui.control.Label
        INDUCTIVOMOTORZ1Lamp            matlab.ui.control.Lamp
        INDUCTIVOMOTORZ1LampLabel       matlab.ui.control.Label
        INDUCTIVOMOTORX2Lamp            matlab.ui.control.Lamp
        INDUCTIVOMOTORX2LampLabel       matlab.ui.control.Label
        Indicador1Motor1Lamp            matlab.ui.control.Lamp
        Indicador1Motor1LampLabel       matlab.ui.control.Label
        CONEXIONSERIALSwitch            matlab.ui.control.Switch
        CONEXIONSERIALSwitchLabel       matlab.ui.control.Label
        INDICADORESPARASENSORESDETEMPERATURALabel  matlab.ui.control.Label
        INDICADORESPARASENSORESFINALESDECARRERALabel  matlab.ui.control.Label
        INDUCTIVOMOTORX1Lamp            matlab.ui.control.Lamp
        INDUCTIVOMOTORX1LampLabel       matlab.ui.control.Label
        Indicador3Motor2Lamp            matlab.ui.control.Lamp
        Indicador3Motor2LampLabel       matlab.ui.control.Label
        Indicador2Motor2Lamp            matlab.ui.control.Lamp
        Indicador2Motor2LampLabel       matlab.ui.control.Label
        Indicador1Motor2Lamp            matlab.ui.control.Lamp
        Indicador1Motor2LampLabel       matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        FinaldecarreraFinalEjeZ2Lamp    matlab.ui.control.Lamp
        FinaldecarreraFinalEjeZ2LampLabel  matlab.ui.control.Label
        FinaldecarreraInicioEjeZ1Lamp   matlab.ui.control.Lamp
        FinaldecarreraInicioEjeZ1LampLabel  matlab.ui.control.Label
        FinaldecarreraFinalEjeX2Lamp    matlab.ui.control.Lamp
        FinaldecarreraFinalEjeX2LampLabel  matlab.ui.control.Label
        FinaldecarreraInicioEjeX1Lamp   matlab.ui.control.Lamp
        FinaldecarreraInicioEjeX1Label  matlab.ui.control.Label
        Indicador3Motor1Lamp            matlab.ui.control.Lamp
        Indicador3Motor1LampLabel       matlab.ui.control.Label
        Indicador2Motor1Lamp            matlab.ui.control.Lamp
        Indicador2Motor1LampLabel       matlab.ui.control.Label
        SENTIDOMOTORXSwitch             matlab.ui.control.Switch
        SENTIDOMOTORXSwitchLabel        matlab.ui.control.Label
        VARIABLESMOTORXLabel            matlab.ui.control.Label
        DisminuirGirosXButton           matlab.ui.control.Button
        AumentarGirosXButton            matlab.ui.control.Button
        DisminuirRPMXButton             matlab.ui.control.Button
        AumentarRPMXButton              matlab.ui.control.Button
    end

    
    properties (Access = private)
        puerto_serial;
        RPM=0; % Description
        GIROS=0;
        Direccion=0;
        RPMZ=0; 
        GIROSZ=0;
        DireccionZ=0;
        ENVIO=0;
        RECIBIR=0;
        Timer;
    end
    methods (Access = private)
        function leerDatos(app)
            data = fscanf(app.puerto_serial,'%d,%d,%d,%d,%d,%d,%d,%d,%d,%d\n'); % Lee los valores del puerto serial  
            if numel(data) == 10 % Asegúrate de que hay dos valores
                temp1 = data(1);
                temp2 = data(2);
                fcx1 = data(3);
                fcx2 = data(4);
                fcz1 = data(5);
                fcz2 = data(6);
                indx1 = data(7);
                indx2 = data(8);
                indz1 = data(9);
                indz2 = data(10);
                app.UITable.Data = [temp1,temp2];
            end
            % Seccion indicador finales de carrera EJE X
            if fcx1 == 1
                app.FinaldecarreraInicioEjeX1Lamp.Color = 'green';
            else
                app.FinaldecarreraInicioEjeX1Lamp.Color = 'red';
            end
            if fcx2 == 1
                app.FinaldecarreraFinalEjeX2Lamp.Color = 'green';
            else
                app.FinaldecarreraFinalEjeX2Lamp.Color = 'red';
            end
            % Seccion indicador finales de carrera EJE Z
            if fcz1 == 1
               app.FinaldecarreraInicioEjeZ1Lamp.Color = 'green';
            else
               app.FinaldecarreraInicioEjeZ1Lamp.Color = 'red';
            end
            if fcz2 == 1
               app.FinaldecarreraFinalEjeZ2Lamp.Color = 'green';
            else
               app.FinaldecarreraFinalEjeZ2Lamp.Color = 'red';
            end
            %Seccion indicador sensores moviles
            if indx1 == 1
               app.INDUCTIVOMOTORX1Lamp.Color = 'yellow';
            else
               app.INDUCTIVOMOTORX1Lamp.Color = 'red';
            end              
            if indx2 == 1
               app.INDUCTIVOMOTORX2Lamp.Color = 'yellow';
            else
               app.INDUCTIVOMOTORX2Lamp.Color = 'red';
            end
            if indz1 == 1
               app.INDUCTIVOMOTORZ1Lamp.Color = 'yellow';
            else
               app.INDUCTIVOMOTORZ1Lamp.Color = 'red';
            end
            if indz2 == 1
               app.INDUCTIVOMOTORZ2Lamp.Color = 'yellow';
            else
               app.INDUCTIVOMOTORZ2Lamp.Color = 'red';
            end
            pause(0.1);
            % Aquí puedes agregar una condición para detener el temporizador si es necesario
            if app.ENVIO == 1
                stop(app.Timer);
                delete(app.Timer);
                app.RECIBIR = 0; % Restablecer el estado
            end
       end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: AumentarRPMXButton
        function AumentarRPMXButtonPushed(app, event)
            if app.RPM < 1200  % Limita el máximo de RPM
                app.RPM = app.RPM + 10;
                app.RPMXEditField.Value=app.RPM;
            end
        end

        % Button pushed function: AumentarGirosXButton
        function AumentarGirosXButtonPushed(app, event)
               app.GIROS = app.GIROS + 1;
               app.GirosXEditField.Value=app.GIROS;
        end

        % Button pushed function: DisminuirRPMXButton
        function DisminuirRPMXButtonPushed(app, event)
            if app.RPM > 0
                app.RPM = app.RPM - 10;
                app.RPMXEditField.Value=app.RPM;
            end
        end

        % Button pushed function: DisminuirGirosXButton
        function DisminuirGirosXButtonPushed(app, event)
            if app.GIROS > 0
                app.GIROS = app.GIROS - 1;
                app.GirosXEditField.Value=app.GIROS;
            end
        end

        % Value changed function: SENTIDOMOTORXSwitch
        function SENTIDOMOTORXSwitchValueChanged(app, event)
            value = app.SENTIDOMOTORXSwitch.Value;
            if strcmp(value,'Der')
                app.Direccion = 0;%derecha
            else
                app.Direccion = 1;% izquierda
            end
        end

        % Value changed function: CONEXIONSERIALSwitch
        function CONEXIONSERIALSwitchValueChanged(app, event)
            value = app.CONEXIONSERIALSwitch.Value;
            if strcmp(value,'CONECTAR')
                delete(instrfind({'port'}, {'COM14'}));
                app.puerto_serial = serial('COM14', 'BaudRate', 9600, 'Terminator', 'LF');
                warning('off', 'MATLAB:serial:fscanf:unsuccessfulRead');
                fopen(app.puerto_serial);
                disp('conexion puerto serial on.');                
            else   
                stop(app.Timer);
                fclose(app.puerto_serial);
                delete(app.puerto_serial);
                clear all;
                disp('conexion puerto serial off.');
            end
                    
        end

        % Button pushed function: AumentarRPMZButton
        function AumentarRPMZButtonPushed(app, event)
             if app.RPMZ < 1200  % Limita el máximo de RPM
                app.RPMZ = app.RPMZ + 10;
                app.RPMZEditField.Value=app.RPMZ;
             end
        end

        % Button pushed function: AumentarGirosZButton
        function AumentarGirosZButtonPushed(app, event)
            app.GIROSZ = app.GIROSZ + 1;
            app.GirosZEditField.Value=app.GIROSZ;
        end

        % Button pushed function: DisminuirRPMZButton
        function DisminuirRPMZButtonPushed(app, event)
            if app.RPMZ > 0
                app.RPMZ = app.RPMZ - 10;
                app.RPMZEditField.Value=app.RPMZ;
            end
        end

        % Button pushed function: DisminuirGirosZButton
        function DisminuirGirosZButtonPushed(app, event)
            if app.GIROSZ > 0
                app.GIROSZ = app.GIROSZ - 1;
                app.GirosZEditField.Value=app.GIROSZ;
            end
        end

        % Value changed function: SENTIDOMOTORZSwitch
        function SENTIDOMOTORZSwitchValueChanged(app, event)
            value = app.SENTIDOMOTORZSwitch.Value;
            if strcmp(value,'Bajar')
                app.DireccionZ = 0;%derecha
            else
                app.DireccionZ = 1;% izquierda
            end
        end

        % Value changed function: ComunicacionButton
        function ComunicacionButtonValueChanged(app, event)
            value = app.ComunicacionButton.Value;
            if value == 1
                app.Timer = timer;
                app.Timer.Period = 0.1; % Intervalo de 0.1 segundos
                app.Timer.ExecutionMode = 'fixedRate'; % Ejecutar en intervalos fijos
                app.Timer.TimerFcn = @(~,~) leerDatos(app); % Callback para leer datos
                start(app.Timer); % Iniciar el temporizador
            else
                fprintf('Girosx:%d RPMx:%d Dirx:%d\nGirosz:%d RPMz:%d Dirz:%d\n', ...
                app.GIROS, app.RPM, app.Direccion, app.GIROSZ, app.RPMZ, app.DireccionZ);
                % Enviar datos
                fprintf(app.puerto_serial, '%d,', app.GIROSZ);
                fprintf(app.puerto_serial, '%d,', app.RPMZ);
                fprintf(app.puerto_serial, '%d,', app.DireccionZ);
                fprintf(app.puerto_serial, '%d,', app.GIROS);
                fprintf(app.puerto_serial, '%d,', app.RPM);
                fprintf(app.puerto_serial, '%d,', app.Direccion);
                pause(0.5);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.AumentarRPMXButton = uibutton(app.UIFigure, 'push');
            app.AumentarRPMXButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarRPMXButtonPushed, true);
            app.AumentarRPMXButton.Text = 'Aumentar RPMX';
            app.DisminuirRPMXButton = uibutton(app.UIFigure, 'push');
            app.DisminuirRPMXButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirRPMXButtonPushed, true);
            app.DisminuirRPMXButton.Text = 'Disminuir RPMX';
            app.AumentarGirosXButton = uibutton(app.UIFigure, 'push');
            app.AumentarGirosXButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarGirosXButtonPushed, true);
            app.AumentarGirosXButton.Text = 'Aumentar GirosX';
            app.DisminuirGirosXButton = uibutton(app.UIFigure, 'push');
            app.DisminuirGirosXButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirGirosXButtonPushed, true);
            app.DisminuirGirosXButton.Text = 'Disminuir GirosX';
            app.VARIABLESMOTORXLabel = uilabel(app.UIFigure);
            app.VARIABLESMOTORXLabel.Text = 'VARIABLES MOTOR X';
            app.SENTIDOMOTORXSwitchLabel = uilabel(app.UIFigure);
            app.SENTIDOMOTORXSwitchLabel.Text = 'SENTIDO MOTOR X';
            app.SENTIDOMOTORXSwitch = uiswitch(app.UIFigure, 'slider');
            app.SENTIDOMOTORXSwitch.Items = {'Der', 'Izq'};
            app.SENTIDOMOTORXSwitch.ValueChangedFcn = createCallbackFcn(app, @SENTIDOMOTORXSwitchValueChanged, true);
            app.SENTIDOMOTORXSwitch.Value = 'Der';
            app.Indicador2Motor1LampLabel = uilabel(app.UIFigure);
            app.Indicador2Motor1LampLabel.Text = 'Indicador 2 Motor 1';
            app.Indicador2Motor1Lamp = uilamp(app.UIFigure);
            app.Indicador3Motor1LampLabel = uilabel(app.UIFigure);
            app.Indicador3Motor1LampLabel.Text = 'Indicador 3 Motor 1';
            app.Indicador3Motor1Lamp = uilamp(app.UIFigure);
            app.FinaldecarreraInicioEjeX1Label = uilabel(app.UIFigure);
            app.FinaldecarreraInicioEjeX1Label.Text = 'Final de carrera Inicio Eje X 1';
            app.FinaldecarreraInicioEjeX1Lamp = uilamp(app.UIFigure);
            app.FinaldecarreraFinalEjeX2LampLabel = uilabel(app.UIFigure);
            app.FinaldecarreraFinalEjeX2LampLabel.Text = 'Final de carrera Final Eje X 2';
            app.FinaldecarreraFinalEjeX2Lamp = uilamp(app.UIFigure);
            app.FinaldecarreraInicioEjeZ1LampLabel = uilabel(app.UIFigure);
            app.FinaldecarreraInicioEjeZ1LampLabel.Text = 'Final de carrera Inicio Eje Z 1';
            app.FinaldecarreraInicioEjeZ1Lamp = uilamp(app.UIFigure);
            app.FinaldecarreraFinalEjeZ2LampLabel = uilabel(app.UIFigure);
            app.FinaldecarreraFinalEjeZ2LampLabel.Text = 'Final de carrera Final Eje Z 2';
            app.FinaldecarreraFinalEjeZ2Lamp = uilamp(app.UIFigure);
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'PT100(1)'; 'PT100(2)'};
            app.UITable.RowName = {};
            app.Indicador1Motor2LampLabel = uilabel(app.UIFigure);
            app.Indicador1Motor2LampLabel.Text = 'Indicador 1 Motor 2';
            app.Indicador1Motor2Lamp = uilamp(app.UIFigure);
            app.Indicador2Motor2LampLabel = uilabel(app.UIFigure);
            app.Indicador2Motor2LampLabel.Text = 'Indicador 2 Motor 2';
            app.Indicador2Motor2Lamp = uilamp(app.UIFigure);
            app.Indicador3Motor2LampLabel = uilabel(app.UIFigure);
            app.Indicador3Motor2LampLabel.Text = 'Indicador 3 Motor 2';
            app.Indicador3Motor2Lamp = uilamp(app.UIFigure);
            app.INDUCTIVOMOTORX1LampLabel = uilabel(app.UIFigure);
            app.INDUCTIVOMOTORX1LampLabel.Text = 'INDUCTIVO MOTORX  1';
            app.INDUCTIVOMOTORX1Lamp = uilamp(app.UIFigure);
            app.INDICADORESPARASENSORESFINALESDECARRERALabel = uilabel(app.UIFigure);
            app.INDICADORESPARASENSORESFINALESDECARRERALabel.Text = ' INDICADORES PARA SENSORES FINALES DE CARRERA';
            app.INDICADORESPARASENSORESDETEMPERATURALabel = uilabel(app.UIFigure);
            app.INDICADORESPARASENSORESDETEMPERATURALabel.Text = 'INDICADORES PARA SENSORES DE TEMPERATURA';
            app.CONEXIONSERIALSwitchLabel = uilabel(app.UIFigure);
            app.CONEXIONSERIALSwitchLabel.Text = 'CONEXION SERIAL';
            app.CONEXIONSERIALSwitch = uiswitch(app.UIFigure, 'slider');
            app.CONEXIONSERIALSwitch.Items = {'DESCONECTAR', 'CONECTAR'};
            app.CONEXIONSERIALSwitch.ValueChangedFcn = createCallbackFcn(app, @CONEXIONSERIALSwitchValueChanged, true);
            app.CONEXIONSERIALSwitch.Value = 'DESCONECTAR';
            app.Indicador1Motor1LampLabel = uilabel(app.UIFigure);
            app.Indicador1Motor1LampLabel.Text = 'Indicador 1 Motor 1';
            app.Indicador1Motor1Lamp = uilamp(app.UIFigure);
            app.INDUCTIVOMOTORX2LampLabel = uilabel(app.UIFigure);
            app.INDUCTIVOMOTORX2LampLabel.Text = 'INDUCTIVO MOTORX  2';
            app.INDUCTIVOMOTORX2Lamp = uilamp(app.UIFigure);
            app.INDUCTIVOMOTORZ1LampLabel = uilabel(app.UIFigure);
            app.INDUCTIVOMOTORZ1LampLabel.Text = 'INDUCTIVO MOTORZ  1';
            app.INDUCTIVOMOTORZ1Lamp = uilamp(app.UIFigure);
            app.INDUCTIVOMOTORY2Label = uilabel(app.UIFigure);
            app.INDUCTIVOMOTORY2Label.Text = 'INDUCTIVO MOTORZ 2';
            app.INDUCTIVOMOTORZ2Lamp = uilamp(app.UIFigure);
            app.RPMZEditFieldLabel = uilabel(app.UIFigure);
            app.RPMZEditFieldLabel.Text = 'RPM Z';
            app.RPMZEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMZEditField.Editable = 'off';
            app.GirosZEditFieldLabel = uilabel(app.UIFigure);
            app.GirosZEditFieldLabel.Text = 'Giros Z';
            app.GirosZEditField = uieditfield(app.UIFigure, 'numeric');
            app.GirosZEditField.Editable = 'off';
            app.DisminuirGirosZButton = uibutton(app.UIFigure, 'push');
            app.DisminuirGirosZButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirGirosZButtonPushed, true);
            app.DisminuirGirosZButton.Text = 'Disminuir GirosZ';
            app.VARIABLESMOTORZLabel = uilabel(app.UIFigure);
            app.VARIABLESMOTORZLabel.Text = 'VARIABLES MOTOR Z';
            app.AumentarRPMZButton = uibutton(app.UIFigure, 'push');
            app.AumentarRPMZButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarRPMZButtonPushed, true);
            app.AumentarRPMZButton.Text = 'Aumentar RPMZ';
            app.SENTIDOMOTORZSwitchLabel = uilabel(app.UIFigure);
            app.SENTIDOMOTORZSwitchLabel.Text = 'SENTIDO MOTOR Z';
            app.SENTIDOMOTORZSwitch = uiswitch(app.UIFigure, 'slider');
            app.SENTIDOMOTORZSwitch.Items = {'Bajar', 'Subir'};
            app.SENTIDOMOTORZSwitch.ValueChangedFcn = createCallbackFcn(app, @SENTIDOMOTORZSwitchValueChanged, true);
            app.SENTIDOMOTORZSwitch.Value = 'Bajar';
            app.AumentarGirosZButton = uibutton(app.UIFigure, 'push');
            app.AumentarGirosZButton.ButtonPushedFcn = createCallbackFcn(app, @AumentarGirosZButtonPushed, true);
            app.AumentarGirosZButton.Text = 'Aumentar GirosZ';
            app.DisminuirRPMZButton = uibutton(app.UIFigure, 'push');
            app.DisminuirRPMZButton.ButtonPushedFcn = createCallbackFcn(app, @DisminuirRPMZButtonPushed, true);
            app.DisminuirRPMZButton.Text = 'Disminuir RPMZ';
            app.RPMXEditFieldLabel = uilabel(app.UIFigure);
            app.RPMXEditFieldLabel.Text = 'RPM X';
            app.RPMXEditField = uieditfield(app.UIFigure, 'numeric');
            app.RPMXEditField.Editable = 'off';
            app.GirosXEditFieldLabel = uilabel(app.UIFigure);
            app.GirosXEditFieldLabel.Text = 'GirosX';
            app.GirosXEditField = uieditfield(app.UIFigure, 'numeric');
            app.GirosXEditField.Editable = 'off';
            app.ComunicacionButton = uibutton(app.UIFigure, 'state');
            app.ComunicacionButton.ValueChangedFcn = createCallbackFcn(app, @ComunicacionButtonValueChanged, true);
            app.ComunicacionButton.Text = 'Comunicacion';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Prueba_app_1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end